classdef coax_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConductorMaterialDropDownLabel  matlab.ui.control.Label
        ConductorMaterialDropDown       matlab.ui.control.DropDown
        DielectricMaterialDropDownLabel  matlab.ui.control.Label
        DielectricMaterialDropDown      matlab.ui.control.DropDown
        InnerRadiusofConductormmEditFieldLabel  matlab.ui.control.Label
        InnerRadiusofConductormmEditField  matlab.ui.control.NumericEditField
        OuterRadiusofConductormmEditFieldLabel  matlab.ui.control.Label
        OuterRadiusofConductormmEditField  matlab.ui.control.NumericEditField
        RealLoadImpedanceOhmsEditFieldLabel  matlab.ui.control.Label
        RealLoadImpedanceOhmsEditField  matlab.ui.control.NumericEditField
        ImaginaryLoadImpedanceOhmsEditFieldLabel  matlab.ui.control.Label
        ImaginaryLoadImpedanceOhmsEditField  matlab.ui.control.NumericEditField
        CableLengthmEditFieldLabel      matlab.ui.control.Label
        CableLengthmEditField           matlab.ui.control.NumericEditField
        SignalFrequencykHzEditFieldLabel  matlab.ui.control.Label
        SignalFrequencykHzEditField     matlab.ui.control.NumericEditField
        GenerateButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        innerRadius
        outerRadius
        realLoadImpedance
        imaginaryLoadImpedance
        cableLength
        radSignalFrequency 
        
        R_dist
        L_dist
        G_dist
        C_dist
        gamma
        alpha
        beta
        Z_0
        refCoeff
        VSWR
        Z_in
        f_cut
        V_br
        
    end
    
    methods (Access = private)
        
        function [R, L, G, C] = generateRLGC(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            h = tf([1 1],[1 2 1]);
            bode(h)
        end

        % Value changed function: InnerRadiusofConductormmEditField
        function InnerRadiusofConductormmEditFieldValueChanged(app, event)
            value = app.InnerRadiusofConductormmEditField.Value;
            app.innerRadius = value * 10e-3;
        end

        % Value changed function: OuterRadiusofConductormmEditField
        function OuterRadiusofConductormmEditFieldValueChanged(app, event)
            value = app.OuterRadiusofConductormmEditField.Value;
            app.outerRadius = value * 10e-3;
        end

        % Value changed function: RealLoadImpedanceOhmsEditField
        function RealLoadImpedanceOhmsEditFieldValueChanged(app, event)
            value = app.RealLoadImpedanceOhmsEditField.Value;
            app.realLoadImpedance = value;
        end

        % Value changed function: 
        % ImaginaryLoadImpedanceOhmsEditField
        function ImaginaryLoadImpedanceOhmsEditFieldValueChanged(app, event)
            value = app.ImaginaryLoadImpedanceOhmsEditField.Value;
            app.imaginaryLoadImpedance = value;
        end

        % Value changed function: CableLengthmEditField
        function CableLengthmEditFieldValueChanged(app, event)
            value = app.CableLengthmEditField.Value;
            app.cableLength = value;
        end

        % Value changed function: SignalFrequencykHzEditField
        function SignalFrequencykHzEditFieldValueChanged(app, event)
            value = app.SignalFrequencykHzEditField.Value;
            app.signalFrequency = value * 10e3;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConductorMaterialDropDownLabel = uilabel(app.UIFigure);
            app.ConductorMaterialDropDownLabel.Text = 'Conductor Material';
            app.ConductorMaterialDropDown = uidropdown(app.UIFigure);
            app.ConductorMaterialDropDown.Items = {'Copper', 'Aluminum', 'Stainless Steel', 'Tin', 'Tungsten', 'Solder', 'Silver', 'Nickel', 'Nichrome', 'Lead', 'Iron (99.8%)', 'Iron (99.96%)', 'Graphite', 'Carbon', 'Gold'};
            app.ConductorMaterialDropDown.ItemsData = {'', ''};
            app.ConductorMaterialDropDown.Value = '';
            app.DielectricMaterialDropDownLabel = uilabel(app.UIFigure);
            app.DielectricMaterialDropDownLabel.Text = 'Dielectric Material';
            app.DielectricMaterialDropDown = uidropdown(app.UIFigure);
            app.DielectricMaterialDropDown.Items = {'Air', 'Alumina', 'Bbarium Titanate', 'Glass', 'Mica', 'Polyethylene', 'Polystyrene', 'Quartz', 'Silicon', 'Soil', 'Teflon', 'Water', 'Seawater'};
            app.DielectricMaterialDropDown.Value = 'Air';
            app.InnerRadiusofConductormmEditFieldLabel = uilabel(app.UIFigure);
            app.InnerRadiusofConductormmEditFieldLabel.Text = 'Inner Radius of Conductor (mm)';
            app.InnerRadiusofConductormmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerRadiusofConductormmEditField.Limits = [0 Inf];
            app.InnerRadiusofConductormmEditField.ValueChangedFcn = createCallbackFcn(app, @InnerRadiusofConductormmEditFieldValueChanged, true);
            app.OuterRadiusofConductormmEditFieldLabel = uilabel(app.UIFigure);
            app.OuterRadiusofConductormmEditFieldLabel.Text = 'Outer Radius of Conductor (mm)';
            app.OuterRadiusofConductormmEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterRadiusofConductormmEditField.Limits = [0 Inf];
            app.OuterRadiusofConductormmEditField.ValueChangedFcn = createCallbackFcn(app, @OuterRadiusofConductormmEditFieldValueChanged, true);
            app.RealLoadImpedanceOhmsEditFieldLabel = uilabel(app.UIFigure);
            app.RealLoadImpedanceOhmsEditFieldLabel.Text = ' Real Load Impedance (Ohms)';
            app.RealLoadImpedanceOhmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RealLoadImpedanceOhmsEditField.Limits = [0 Inf];
            app.RealLoadImpedanceOhmsEditField.ValueChangedFcn = createCallbackFcn(app, @RealLoadImpedanceOhmsEditFieldValueChanged, true);
            app.ImaginaryLoadImpedanceOhmsEditFieldLabel = uilabel(app.UIFigure);
            app.ImaginaryLoadImpedanceOhmsEditFieldLabel.Text = 'Imaginary Load Impedance (Ohms)';
            app.ImaginaryLoadImpedanceOhmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImaginaryLoadImpedanceOhmsEditField.ValueChangedFcn = createCallbackFcn(app, @ImaginaryLoadImpedanceOhmsEditFieldValueChanged, true);
            app.CableLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.CableLengthmEditFieldLabel.Text = 'Cable Length (m)';
            app.CableLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CableLengthmEditField.Limits = [0 Inf];
            app.CableLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @CableLengthmEditFieldValueChanged, true);
            app.SignalFrequencykHzEditFieldLabel = uilabel(app.UIFigure);
            app.SignalFrequencykHzEditFieldLabel.Text = 'Signal Frequency (kHz)';
            app.SignalFrequencykHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalFrequencykHzEditField.Limits = [0 Inf];
            app.SignalFrequencykHzEditField.ValueChangedFcn = createCallbackFcn(app, @SignalFrequencykHzEditFieldValueChanged, true);
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coax_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end